classdef UI_EyeTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalizzaTrendGruppoButton  matlab.ui.control.Button
        StoricoTable               matlab.ui.control.Table
        AnalizzaTrendButton        matlab.ui.control.Button
        PazienteDropDown           matlab.ui.control.DropDown
        PazienteDropDownLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Database_finale % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Carico il file dal disco dentro la proprietà dell'app
dati_salvati = load('Database_eyetracking.mat');
app.Database_finale = dati_salvati.Database_finale;

% 2. Estraggo gli ID dei pazienti e li inserisco nel DropDown
pazienti_univoci = unique(app.Database_finale.ID_Paziente);
app.PazienteDropDown.Items = string(pazienti_univoci);

        end

        % Button pushed function: AnalizzaTrendButton
        function AnalizzaTrendButtonPushed(app, event)

    % 1. Legge il valore selezionato nel DropDown come testo
    valore_selezionato = string(app.PazienteDropDown.Value);

    % 2. Filtra il database per il paziente scelto
    Filtro = string(app.Database_finale.ID_Paziente) == valore_selezionato;
    Dati_Paziente = app.Database_finale(Filtro, :);

    % 3. Avviso se non ci sono dati
    if isempty(Dati_Paziente)
        uialert(app.UIFigure, 'Nessun dato trovato per il paziente selezionato.', 'Attenzione');
        return;
    end

    % --- FIX: ordino esplicitamente in ordine cronologico ---
    Dati_Paziente = sortrows(Dati_Paziente, 'ID_Acquisizione');

    % --- PULIZIA GRAFICO PRECEDENTE ---
    cla(app.UIAxes);

    % 4. GRAFICO A BLOCCHI TEMPORALI (Latenza)
    latenza = Dati_Paziente.Latenza_ms;
    n_righe = height(Dati_Paziente);

    num_blocchi = 10;
    if n_righe < num_blocchi
        num_blocchi = n_righe;
    end

    dim_blocco = floor(n_righe / num_blocchi);

    x_trend = zeros(num_blocchi, 1);
    y_trend = zeros(num_blocchi, 1);
    y_std   = zeros(num_blocchi, 1);   % <-- nuovo: deviazione standard per blocco

    for i = 1:num_blocchi
        idx_start = (i - 1) * dim_blocco + 1;
        if i == num_blocchi
            idx_end = n_righe;
        else
            idx_end = i * dim_blocco;
        end

        x_trend(i) = i;
        y_trend(i) = mean(latenza(idx_start:idx_end), 'omitnan');
        y_std(i)   = std(latenza(idx_start:idx_end), 'omitnan');
    end

    % --- FIX: grafico con banda di deviazione standard invece di linea nuda ---
    errorbar(app.UIAxes, x_trend, y_trend, y_std, '-o', ...
             'LineWidth', 2.5, 'MarkerSize', 8, ...
             'MarkerFaceColor', '#D95319', 'Color', '#D95319', 'CapSize', 6);

    hold(app.UIAxes, 'on');

    % Linea di riferimento orizzontale con la media globale della latenza
    media_globale = mean(latenza, 'omitnan');
    yline(app.UIAxes, media_globale, '--', ...
          'Color', '#0072BD', 'LineWidth', 1.5, ...
          'Label', sprintf('Media: %.1f ms', media_globale), ...
          'LabelHorizontalAlignment', 'left');

    hold(app.UIAxes, 'off');

    % Titolo dinamico chiaro e professionale
    gruppo_corrente = string(Dati_Paziente.Gruppo_sano_patologico(1));
    testo_titolo = sprintf('Trend Latenza - %s (%s)', char(valore_selezionato), char(gruppo_corrente));
    title(app.UIAxes, testo_titolo);

    xlabel(app.UIAxes, "Fasi del Test (10 Blocchi Cronologici)");
    ylabel(app.UIAxes, "Latenza Media (ms)");
    grid(app.UIAxes, 'on');

    % --- FIX: asse Y dinamico, centrato sui dati invece che fisso 0-1000 ---
    range_y = max(y_trend + y_std) - min(y_trend - y_std);
    if range_y <= 0
        range_y = 10;
    end
    margine = range_y * 0.3;
    app.UIAxes.YLim = [min(y_trend - y_std) - margine, max(y_trend + y_std) + margine];
    app.UIAxes.XLim = [0.5, num_blocchi + 0.5];
    app.UIAxes.XTick = 1:num_blocchi;

    % 5. TABELLA RIPRISTINATA E CORRETTA
    app.StoricoTable.Data = Dati_Paziente;
    app.StoricoTable.ColumnName = Dati_Paziente.Properties.VariableNames;
        end

        % Button pushed function: AnalizzaTrendGruppoButton
        function AnalizzaTrendGruppoButtonPushed(app, event)

    num_blocchi = 10;
    gruppi = unique(app.Database_finale.Gruppo_sano_patologico);
    gruppi = gruppi(~ismissing(gruppi));

    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    x_trend = 1:num_blocchi;
    legend_labels = {};

    for g = 1:numel(gruppi)

        gruppo_corrente = string(gruppi(g));

        % Filtro tutte le righe di questo gruppo
        Filtro_gruppo = string(app.Database_finale.Gruppo_sano_patologico) == gruppo_corrente;
        Dati_Gruppo = app.Database_finale(Filtro_gruppo, :);

        pazienti_gruppo = unique(Dati_Gruppo.ID_Paziente);
        matrice_trend = nan(numel(pazienti_gruppo), num_blocchi);

        % Per ogni paziente del gruppo, calcolo i 10 blocchi cronologici
        for p = 1:numel(pazienti_gruppo)
            Filtro_paziente = string(Dati_Gruppo.ID_Paziente) == string(pazienti_gruppo(p));
            Dati_Paziente = Dati_Gruppo(Filtro_paziente, :);
            Dati_Paziente = sortrows(Dati_Paziente, 'ID_Acquisizione');

            latenza = Dati_Paziente.Latenza_ms;
            n_righe = height(Dati_Paziente);

            if n_righe < num_blocchi
                continue; % paziente con troppo pochi dati, lo salto
            end

            dim_blocco = floor(n_righe / num_blocchi);
            for i = 1:num_blocchi
                idx_start = (i - 1) * dim_blocco + 1;
                if i == num_blocchi
                    idx_end = n_righe;
                else
                    idx_end = i * dim_blocco;
                end
                matrice_trend(p, i) = mean(latenza(idx_start:idx_end), 'omitnan');
            end
        end

        % Media del gruppo, blocco per blocco (media tra tutti i pazienti)
        media_gruppo = mean(matrice_trend, 1, 'omitnan');

        % Colore fisso per gruppo, con un semplice if/elseif
        if gruppo_corrente == "Parkinson"
            colore = [0.8500 0.3250 0.0980];   % arancione
        elseif gruppo_corrente == "Alzheimer"
            colore = [0.4940 0.1840 0.5560];   % viola
        elseif gruppo_corrente == "Controllo"
            colore = [0 0.4470 0.7410];        % blu
        else
            colore = [0.4660 0.6740 0.1880];   % verde, per eventuali altri gruppi
        end

        plot(app.UIAxes, x_trend, media_gruppo, '-o', ...
             'LineWidth', 2.5, 'MarkerSize', 6, ...
             'MarkerFaceColor', colore, 'Color', colore);

        legend_labels{end+1} = sprintf('%s (n=%d)', gruppo_corrente, numel(pazienti_gruppo)); %#ok<AGROW>
    end

    hold(app.UIAxes, 'off');

    title(app.UIAxes, 'Confronto Trend Latenza tra Gruppi');
    xlabel(app.UIAxes, "Fasi del Test (10 Blocchi Cronologici)");
    ylabel(app.UIAxes, "Latenza Media (ms)");
    grid(app.UIAxes, 'on');
    app.UIAxes.XLim = [0.5, num_blocchi + 0.5];
    app.UIAxes.XTick = 1:num_blocchi;
    legend(app.UIAxes, legend_labels, 'Location', 'best');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TrendAxes')
            xlabel(app.UIAxes, 'ID Acquisizione')
            ylabel(app.UIAxes, 'Latenza ms')
            zlabel(app.UIAxes, 'Z')
            app.PazienteDropDownLabel = uilabel(app.UIFigure);
            app.PazienteDropDownLabel.Text = 'PazienteDropDown';
            app.PazienteDropDown = uidropdown(app.UIFigure);
            app.AnalizzaTrendButton = uibutton(app.UIFigure, 'push');
            app.AnalizzaTrendButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaTrendButtonPushed, true);
            app.AnalizzaTrendButton.Text = 'Analizza Trend';
            app.StoricoTable = uitable(app.UIFigure);
            app.StoricoTable.ColumnName = {'ID_Paziente'; 'Eta'; 'Sesso'; 'Gruppo_sano_patologico'; 'Scolarita'; 'ID_Diagnosi'; 'Patologia'; 'Punteggio_Clinico'; 'ID_Acquisizione'; 'Data_Test'; 'Stato_Farmaco(ON/OFF)'; 'Task'; 'Freq_Campionamento_Hz'; 'ID_Metrica'; 'Stimolo'; 'Categoria'; 'Latenza_ms'; 'Durata_Fissazione_ms'; 'Diametro_Pupillare_Medio_mm'; 'Ampiezza_deg'; 'Velocita_Picco_deg_s'; 'Guadagno_Pursuit'; 'Error_Rate_Perc'};
            app.StoricoTable.RowName = {};
            app.AnalizzaTrendGruppoButton = uibutton(app.UIFigure, 'push');
            app.AnalizzaTrendGruppoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaTrendGruppoButtonPushed, true);
            app.AnalizzaTrendGruppoButton.Text = 'Confronta gruppi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_EyeTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end